classdef GUItest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EmergencyStopButton       matlab.ui.control.Button
        ResetESTOPandRobotButton  matlab.ui.control.Button
        LoadEnvironmentButton     matlab.ui.control.Button
        Drink1Button              matlab.ui.control.Button
        Drink2Button              matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Drink1Button
        function Drink1ButtonPushed(app, event)
            % Disable buttons to prevent multiple clicks
            app.Drink1Button.Enable = 'off';
            app.Drink2Button.Enable = 'off';
            app.EmergencyStopButton.Enable = 'on';
            
            % Run function code for drink 1
            for i = 1:1
                % replace pause with function
                pause(4); % Simulate some work being done
        
                % Check if the emergency stop is active
                if app.EmergencyStopButton.UserData == true
                    break; % Exit the loop early if emergency stop is triggered
                end
            end
            
            if app.EmergencyStopButton.UserData == false
            % Re-enable buttons after function is complete
            app.Drink1Button.Enable = 'on';
            app.Drink2Button.Enable = 'on';
            app.EmergencyStopButton.Enable = 'off';
            end
        end

        % Button pushed function: Drink2Button
        function Drink2ButtonPushed(app, event)
            % Disable buttons to prevent multiple clicks
            app.Drink1Button.Enable = 'off';
            app.Drink2Button.Enable = 'off';
            app.EmergencyStopButton.Enable = 'on';
            
            % Run function code for drink 2
            for i = 1:1
                % replace pause with function
                pause(4); % Simulate some work being done
        
                % Check if the emergency stop is active
                if app.EmergencyStopButton.UserData == true
                    break; % Exit the loop early if emergency stop is triggered
                end
            end
            
            if app.EmergencyStopButton.UserData == false
            % Re-enable buttons after function is complete
            app.Drink1Button.Enable = 'on';
            app.Drink2Button.Enable = 'on';
            app.EmergencyStopButton.Enable = 'off';
            end
        end

        % Button pushed function: LoadEnvironmentButton
        function LoadEnvironmentButtonPushed(app, event)
            app.LoadEnvironmentButton.Enable = 'off';
            
            % Load environment
            pause(0);

            % Re-enable buttons after function is complete
            app.Drink1Button.Enable = 'on';
            app.Drink2Button.Enable = 'on';
            app.EmergencyStopButton.UserData = false;
        end

        % Button pushed function: ResetESTOPandRobotButton
        function ResetESTOPandRobotButtonPushed(app, event)
            app.ResetESTOPandRobotButton.Enable = 'off';
            
            % code to reset robot to initial starting position 
            pause(3);
            
            app.Drink1Button.Enable = 'on';
            app.Drink2Button.Enable = 'on';
            app.EmergencyStopButton.UserData = false;
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            app.EmergencyStopButton.UserData = true;
            app.Drink1Button.Enable = 'off';
            app.Drink2Button.Enable = 'off';
            
            % code that stops robot

            app.ResetESTOPandRobotButton.Enable = 'on';
            app.EmergencyStopButton.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Bar Simulation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Drink2Button = uibutton(app.UIFigure, 'push');
            app.Drink2Button.ButtonPushedFcn = createCallbackFcn(app, @Drink2ButtonPushed, true);
            app.Drink2Button.Enable = 'off';
            app.Drink2Button.Text = 'Drink2';
            app.Drink1Button = uibutton(app.UIFigure, 'push');
            app.Drink1Button.ButtonPushedFcn = createCallbackFcn(app, @Drink1ButtonPushed, true);
            app.Drink1Button.Enable = 'off';
            app.Drink1Button.Text = 'Drink1';
            app.LoadEnvironmentButton = uibutton(app.UIFigure, 'push');
            app.LoadEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEnvironmentButtonPushed, true);
            app.LoadEnvironmentButton.Text = 'Load Environment';
            app.ResetESTOPandRobotButton = uibutton(app.UIFigure, 'push');
            app.ResetESTOPandRobotButton.ButtonPushedFcn = createCallbackFcn(app, @ResetESTOPandRobotButtonPushed, true);
            app.ResetESTOPandRobotButton.Enable = 'off';
            app.ResetESTOPandRobotButton.Text = 'Reset ESTOP and  Robot';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Enable = 'off';
            app.EmergencyStopButton.Text = 'Emergency Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUItest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end